classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        removenoiseButton  matlab.ui.control.Button
        AddnoiseButton     matlab.ui.control.Button
        TakeimageButton    matlab.ui.control.Button
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeimageButton
        function TakeimageButtonPushed(app, event)
            global a;
            [filename,pathname]=uigetfile("*.*",'Pick a image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);

        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            global a;
            global j;
            j=a;
            j=imnoise(j,"salt & pepper",0.3);
            imshow(j,'Parent',app.UIAxes2);
        end

        % Button pushed function: removenoiseButton
        function removenoiseButtonPushed(app, event)
            global j;
            p=medfilt3(j,[5,5,3]);
            imshow(p,'Parent',app.UIAxes3);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'add noise')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'remove noise')
            app.TakeimageButton = uibutton(app.UIFigure, 'push');
            app.TakeimageButton.ButtonPushedFcn = createCallbackFcn(app, @TakeimageButtonPushed, true);
            app.TakeimageButton.Text = 'Take image';
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.removenoiseButton = uibutton(app.UIFigure, 'push');
            app.removenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @removenoiseButtonPushed, true);
            app.removenoiseButton.Text = 'remove noise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end